classdef net_monitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LinkStatisticsLabel  matlab.ui.control.Label
        UpdateButton         matlab.ui.control.Button
        NodeDropDown         matlab.ui.control.DropDown
        NodeDropDownLabel    matlab.ui.control.Label
        UIAxes_NumConn       matlab.ui.control.UIAxes
        UIAxes_Doppler       matlab.ui.control.UIAxes
        UIAxes_PropDelay     matlab.ui.control.UIAxes
        UIAxes_LinkBudget    matlab.ui.control.UIAxes
        UIAxes_Distance      matlab.ui.control.UIAxes
    end

    %%
    %
    %   TO DO:
    %       - Monitorizar os plots 
    %           - Atualizar os plots com base em dados da network_map
    %
    %%
    properties (Access = private)
        MainApp % Description
        timer_monitor;
        nodeToDisplay;
        NODE
        DIST_PLOT = [];
        LL_PLOT = [];
        PD_PLOT = [];
        DOP_PLOT = [];
        NUM_CON_PLOT = [];
        dist_offset = 0;
        ll_offset = 0;
        pd_offset = 0;
        dop_offset = 0;
        num_con_offset = 0;
        dist_x_init_min = 1;
        dist_x_init_max = 200;
    end

    methods (Access = public)
    
        function timerInit(app)
            app.timer_monitor = timer;
            
            app.timer_monitor.TimerFcn = @app.updatePlots;
            app.timer_monitor.Period = 5;
            app.timer_monitor.StartDelay = 0;
            app.timer_monitor.ExecutionMode = 'fixedSpacing';
            
        end

        function timerStart(app)
            start(app.timer_monitor)
        end
        
        function timerStop(app)
            stop(app.timer_monitor);
        end

        function updatePlots(app,~,~)
            app.updatePlotsDistance(app.nodeToDisplay);
            app.updatePlotsLinkBudge(app.nodeToDisplay);
            app.updatePlotsPropDelay(app.nodeToDisplay);
            app.updatePlotsDoppler(app.nodeToDisplay);
            app.updatePlotsNumConn(app.nodeToDisplay);

            drawnow;
        end
    end

    methods (Access = private)
        
        %% DISTANCE PLOTS
        function updatePlotsDistance(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            distance = app.MainApp.SIM_DATA(node).distance;
            time = app.MainApp.SIM_DATA(node).time;

            g = 1;
            legend_plot = [];
            
            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    set(app.DIST_PLOT(g), 'XData', time, 'YData', distance(:,i));
                    legend_plot = [legend_plot string(i)];
                    g = g + 1;
                end

            end

            legend(app.UIAxes_Distance, legend_plot);

            if time(end) > (200 + app.dist_offset)
                app.dist_offset = app.dist_offset + 100;
                app.UIAxes_Distance.XLim = [app.dist_x_init_min + app.dist_offset  app.dist_x_init_max + app.dist_offset];
            end
            
        end
        
        function createDistancePlot(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            distance = app.MainApp.SIM_DATA(node).distance;
            time = app.MainApp.SIM_DATA(node).time;

            legend_plot = [];

            hold (app.UIAxes_Distance, "on");
            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    app.DIST_PLOT = [app.DIST_PLOT plot(app.UIAxes_Distance, time, distance(:,i), '.-')];
                    legend_plot = [legend_plot string(i)];
                end

            end
            
            app.UIAxes_Distance.XLim = [app.dist_x_init_min app.dist_x_init_max];

            legend(app.UIAxes_Distance, legend_plot);
            hold (app.UIAxes_Distance, "off");

        end

        %% LINK BUDGET PLOTS
        function updatePlotsLinkBudge(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            link_budget = app.MainApp.SIM_DATA(node).link_budget;
            time = app.MainApp.SIM_DATA(node).time;

            g = 1;
            legend_plot = [];
            
            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    set(app.LL_PLOT(g), 'XData', time, 'YData', link_budget(:,i));
                    legend_plot = [legend_plot string(i)];
                    g = g + 1;
                end

            end

            legend(app.UIAxes_LinkBudget, legend_plot);

            if time(end) > (200 + app.ll_offset)
                app.ll_offset = app.ll_offset + 100;
                app.UIAxes_LinkBudget.XLim = [app.dist_x_init_min + app.ll_offset  app.dist_x_init_max + app.ll_offset];
            end
            

        end

        function createLinkBudgetPlot(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            link_budget = app.MainApp.SIM_DATA(node).link_budget;
            time = app.MainApp.SIM_DATA(node).time;

            legend_plot = [];

            hold (app.UIAxes_LinkBudget, "on");

            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    app.LL_PLOT = [app.LL_PLOT plot(app.UIAxes_LinkBudget, time, link_budget(:,i), '.-')];
                    legend_plot = [legend_plot string(i)];
                end

            end
            
            app.UIAxes_LinkBudget.XLim = [app.dist_x_init_min app.dist_x_init_max];

            legend(app.UIAxes_LinkBudget, legend_plot);
            hold (app.UIAxes_LinkBudget, "off");

        end

        %% PROPAGATION DELAY
        function updatePlotsPropDelay(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            prop_delay = app.MainApp.SIM_DATA(node).prop_delay;
            time = app.MainApp.SIM_DATA(node).time;

            g = 1;
            legend_plot = [];
            
            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    set(app.PD_PLOT(g), 'XData', time, 'YData', prop_delay(:,i)*10^6);
                    legend_plot = [legend_plot string(i)];
                    g = g + 1;
                end

            end

            legend(app.UIAxes_PropDelay, legend_plot);

            if time(end) > (200 + app.pd_offset)
                app.pd_offset = app.pd_offset + 100;
                app.UIAxes_PropDelay.XLim = [app.dist_x_init_min + app.pd_offset  app.dist_x_init_max + app.pd_offset];
            end
            

        end

        function createPropDelayPlot(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            prop_delay = app.MainApp.SIM_DATA(node).prop_delay;
            time = app.MainApp.SIM_DATA(node).time;

            legend_plot = [];

            hold (app.UIAxes_PropDelay, "on");

            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    app.PD_PLOT = [app.PD_PLOT plot(app.UIAxes_PropDelay, time, prop_delay(:,i)*10^6, '.-')];
                    legend_plot = [legend_plot string(i)];
                end

            end
            
            app.UIAxes_PropDelay.XLim = [app.dist_x_init_min app.dist_x_init_max];
           

            legend(app.UIAxes_PropDelay, legend_plot);
            hold (app.UIAxes_PropDelay, "off");

        end

        %% DOPPLER
        function updatePlotsDoppler(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            doppler = app.MainApp.SIM_DATA(node).doppler;
            time = app.MainApp.SIM_DATA(node).time;

            g = 1;
            legend_plot = [];
            
            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    set(app.DOP_PLOT(g), 'XData', time, 'YData', doppler(:,i));
                    legend_plot = [legend_plot string(i)];
                    g = g + 1;
                end

            end

            legend(app.UIAxes_Doppler, legend_plot);

            if time(end) > (200 + app.dop_offset)
                app.dop_offset = app.dop_offset + 100;
                app.UIAxes_Doppler.XLim = [app.dist_x_init_min + app.dop_offset  app.dist_x_init_max + app.dop_offset];
            end
            

        end

        function createDopplerPlot(app, node)
            
            node_id = app.MainApp.NETWORK.node_list(node).id;
            doppler = app.MainApp.SIM_DATA(node).doppler;
            time = app.MainApp.SIM_DATA(node).time;

            legend_plot = [];

            hold (app.UIAxes_Doppler, "on");

            for i = 1:length(app.MainApp.NETWORK.node_list)

                if ne(i, node_id)
                    app.DOP_PLOT = [app.DOP_PLOT plot(app.UIAxes_Doppler, time, doppler(:,i), '.-')];
                    legend_plot = [legend_plot string(i)];
                end

            end
            
            app.UIAxes_Doppler.XLim = [app.dist_x_init_min app.dist_x_init_max];
           

            legend(app.UIAxes_Doppler, legend_plot);
            hold (app.UIAxes_Doppler, "off");

        end

        %% NUMBER OF POSSIBLE CONNECTIONS
        function updatePlotsNumConn(app, node)
            
            possible_connections = (app.MainApp.SIM_DATA(node).link_budget > app.MainApp.NETWORK.node_list(node).sensitivity) & ((app.MainApp.SIM_DATA(node).link_budget < 0));
            time = app.MainApp.SIM_DATA(node).time;
            
            possible_connections = sum(possible_connections,2);
            
            set(app.NUM_CON_PLOT, 'XData', time, 'YData', possible_connections);
            
            if time(end) > (200 + app.num_con_offset)
                app.num_con_offset = app.num_con_offset + 100;
                app.UIAxes_NumConn.XLim = [app.dist_x_init_min + app.num_con_offset  app.dist_x_init_max + app.num_con_offset];
            end
            

        end

        function createNumConnPlot(app, node)
            
            possible_connections = (app.MainApp.SIM_DATA(node).link_budget > app.MainApp.NETWORK.node_list(node).sensitivity) & ((app.MainApp.SIM_DATA(node).link_budget < 0));
            time = app.MainApp.SIM_DATA(node).time;

            hold (app.UIAxes_NumConn, "on");

            app.NUM_CON_PLOT = plot(app.UIAxes_NumConn, time, possible_connections, '.-');
            
            app.UIAxes_NumConn.XLim = [app.dist_x_init_min app.dist_x_init_max];
            
            hold (app.UIAxes_NumConn, "off");

        end

        
        %% DROPDOWN LIST
        function updateNodeDropDown(app)
            
            items = [];
            for k = 1:length(app.MainApp.NETWORK.node_list)
                items = [items cellstr(string(app.MainApp.NETWORK.node_list(k).id))];
            end

            app.NodeDropDown.Items = items;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            app.UIFigure.Visible = 'off';

            movegui(app.UIFigure, "northeast");

            app.MainApp = mainApp;
            
            app.updateNodeDropDown();
            app.NodeDropDown.Value = "1";
            app.nodeToDisplay = str2double(app.NodeDropDown.Value);
            
            app.createDistancePlot(app.nodeToDisplay);
            app.createLinkBudgetPlot(app.nodeToDisplay);
            app.createPropDelayPlot(app.nodeToDisplay);
            app.createDopplerPlot(app.nodeToDisplay);
            app.createNumConnPlot(app.nodeToDisplay);

            app.timerInit();
            app.timerStart();    

            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            app.nodeToDisplay = str2double(app.NodeDropDown.Value);
            
            app.updatePlots();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_Distance = uiaxes(app.UIFigure);
            title(app.UIAxes_Distance, 'Distance')
            ylabel(app.UIAxes_Distance, 'Km')
            zlabel(app.UIAxes_Distance, 'Z')
            app.UIAxes_LinkBudget = uiaxes(app.UIFigure);
            title(app.UIAxes_LinkBudget, 'Link Budget')
            ylabel(app.UIAxes_LinkBudget, 'dB')
            zlabel(app.UIAxes_LinkBudget, 'Z')
            app.UIAxes_PropDelay = uiaxes(app.UIFigure);
            title(app.UIAxes_PropDelay, 'Propagation Delay')
            ylabel(app.UIAxes_PropDelay, 'us')
            zlabel(app.UIAxes_PropDelay, 'Z')
            app.UIAxes_Doppler = uiaxes(app.UIFigure);
            title(app.UIAxes_Doppler, 'Doppler')
            xlabel(app.UIAxes_Doppler, 'time (seconds)')
            ylabel(app.UIAxes_Doppler, 'MHz')
            zlabel(app.UIAxes_Doppler, 'Z')
            app.UIAxes_NumConn = uiaxes(app.UIFigure);
            title(app.UIAxes_NumConn, 'Number of Possible Connections')
            zlabel(app.UIAxes_NumConn, 'Z')
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4'};
            app.NodeDropDown.Value = '1';
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.LinkStatisticsLabel = uilabel(app.UIFigure);
            app.LinkStatisticsLabel.Text = 'Link Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = net_monitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end