classdef network_map < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TrafficSwitch                matlab.ui.control.Switch
        TrafficSwitchLabel           matlab.ui.control.Label
        Lamp_2                       matlab.ui.control.Lamp
        StopSimButton                matlab.ui.control.Button
        SwitchNodeNameLabel          matlab.ui.control.Label
        SwitchNodeName               matlab.ui.control.Switch
        SwitchConnectionCircleLabel  matlab.ui.control.Label
        SwitchConnectionCircle       matlab.ui.control.Switch
        Lamp                         matlab.ui.control.Lamp
        MovementSwitch               matlab.ui.control.Switch
        MovementSwitchLabel          matlab.ui.control.Label
        NodeConfigurationPanel       matlab.ui.container.Panel
        ModifyNodeButton             matlab.ui.control.Button
        VelocityyEditField           matlab.ui.control.NumericEditField
        VelocityyEditFieldLabel      matlab.ui.control.Label
        VelocityxEditField           matlab.ui.control.NumericEditField
        VelocityxEditFieldLabel      matlab.ui.control.Label
        PositionyEditField           matlab.ui.control.NumericEditField
        PositionyEditFieldLabel      matlab.ui.control.Label
        PositionxEditField           matlab.ui.control.NumericEditField
        PositionxEditFieldLabel      matlab.ui.control.Label
        NodeDropDown                 matlab.ui.control.DropDown
        NodeDropDownLabel            matlab.ui.control.Label
        SimulationTimeField          matlab.ui.control.TextArea
        SimulationTimeLabel          matlab.ui.control.Label
        MonitorButton                matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    %%
    %
    %   LOG_TX_PACKET
    %   tx_time | source_add | pkt_id | dest_add
    %
    %   LOG_RX_PACKET
    %   rx_time | source_add | pkt_id | dest_add | rx_node 
    %
    %   Calcular: 
    %       - Número de pacotes perdidos
    %       - Número de pacotes duplicados
    %%


    %%
    %
    %   TO-DO:
    %       - Criar envio e receçao de pacotes (criar tabela)
    %       - Utilizar ficheiro separado para geriar pacotes e ler pacotes
    %       - Janela de configuração de Nó nesta app
    %%
    properties (Access = public)
        CallingApp % Description
        NetMon
        NODE_LIST
        radius = 1.0;
        timer_sim_t;
        simulationTime;
        timer_mov_t;
        showConnection = 0;
        showName = 1;
        SIM_DATA;
        timer_sim_prev_status = 0;
        timer_mov_prev_status = 0;
        timer_traf_prev_status = 0;
        timer_traf_t;
        NETWORK;
        LOG_PKT = struct('tx_packet', [], 'rx_packet', []);
    end
    
    %% ALL TIMERS
    methods (Access = private)
  
        function StopAllTimers(app)
            
            % SIMULATION TIMER
            R = get(app.timer_sim_t, 'Running');

            if isequal(R, 'on')
                app.timer_sim_prev_status = 1;
            else
                app.timer_sim_prev_status = 0;
            end

            app.timerSimStop();
            
            % MOVEMENT TIMER
            R = get(app.timer_mov_t, 'Running');

            if isequal(R, 'on')
                app.timer_mov_prev_status = 1;
            else
                app.timer_mov_prev_status = 0;
            end

            app.timerMoveStop();
    
            % TRAFIC TIMER
            R = get(app.timer_traf_t, 'Running');

            if isequal(R, 'on')
                app.timer_traf_prev_status = 1;
            else
                app.timer_traf_prev_status = 0;
            end

            app.timerTrafficStop();
        end

        function ResumeAllTimers(app)
            
            if app.timer_sim_prev_status == 1
                app.timerSimStart();
            end

            if app.timer_mov_prev_status == 1
                app.timerMoveStart();
            end

            if app.timer_traf_prev_status == 1
                app.timerTrafficStart();
            end
        end

    end

    %% Simulation Timing
    methods (Access = private)

        function timerSimInit(app)
            app.timer_sim_t = timer;
            
            app.timer_sim_t.TimerFcn = @app.updateSimTime;
            app.timer_sim_t.Period = 0.1;
            app.timer_sim_t.StartDelay = 0;
            app.timer_sim_t.ExecutionMode = 'fixedSpacing';
            
            app.simulationTime = 0;
            sec = seconds(app.simulationTime);
            sec.Format = 'hh:mm:ss';
            app.SimulationTimeField.Value = string(sec);
        end

        function timerSimStart(app)
            start(app.timer_sim_t);
        end
        
        function timerSimStop(app)
            stop(app.timer_sim_t);
        end

        function updateSimTime(app,~,~)
            app.simulationTime = app.simulationTime + app.timer_sim_t.Period;
            sec = seconds(app.simulationTime);
            sec.Format = 'hh:mm:ss';
            app.SimulationTimeField.Value = string(sec);

            app.gatherData();
        end
    end
    
    %% Gather Simulation Data
    methods(Access = private)

        function dist = computeDistance(app, source_node, dest_node, node_list)
                        
            x_src = node_list(source_node).x;
            y_src = node_list(source_node).y;
        
            x_dest = node_list(dest_node).x;
            y_dest = node_list(dest_node).y;

            dist = sqrt((x_src - x_dest)^2+(y_src - y_dest)^2);

        end

        function freq = computeDoppler(app, source_node, dest_node, node_list, distance)
            
            len = length(distance(:,1));
            original_freq = node_list(source_node).frequency;
            
            if len == 1
                freq = node_list(source_node).frequency;
            else
                velocity = (distance(len, dest_node) - distance(len-1, dest_node));

                freq = original_freq*(299792.458 + velocity)/(299792.458 + 0);
            end
        end
        
        function gatherData(app)

            for i = 1:length(app.NODE_LIST)

                dist = zeros(1, length(app.NODE_LIST));
                ll = zeros(1, length(app.NODE_LIST));
                lb = zeros(1, length(app.NODE_LIST));
                prop_delay = zeros(1, length(app.NODE_LIST));
                doppler = zeros(1, length(app.NODE_LIST));

                frequency = app.NODE_LIST(i).frequency;
                tx_power = app.NODE_LIST(i).tx_power;
                
                for k = 1:length(app.NODE_LIST)
                        dist(k) = app.computeDistance(i, k, app.NODE_LIST);
                        prop_delay(k) = dist(k) / 299792.458;
                    if (dist(k) ~= 0)
                        ll(k) = friis(frequency, dist(k));
                        lb(k) = tx_power - ll(k);
                    end
                end

                app.SIM_DATA(i).distance = [app.SIM_DATA(i).distance; dist];
                app.SIM_DATA(i).link_budget = [app.SIM_DATA(i).link_budget; lb];
                app.SIM_DATA(i).prop_delay = [app.SIM_DATA(i).prop_delay; prop_delay];

                for k= 1:length(app.NODE_LIST)
                        doppler(k) = app.computeDoppler(i, k, app.NODE_LIST, app.SIM_DATA(i).distance);
                end

                app.SIM_DATA(i).doppler = [app.SIM_DATA(i).doppler; doppler];
                app.SIM_DATA(i).counter = app.SIM_DATA(i).counter + 1;
                app.SIM_DATA(i).time = [app.SIM_DATA(i).time  app.simulationTime];

                if app.SIM_DATA(i).counter > (200/app.timer_sim_t.Period)
                    app.SIM_DATA(i).counter = (200/app.timer_sim_t.Period);
                    app.SIM_DATA(i).distance = app.SIM_DATA(i).distance(2:end,:);
                    app.SIM_DATA(i).link_budget = app.SIM_DATA(i).link_budget(2:end,:);
                    app.SIM_DATA(i).prop_delay = app.SIM_DATA(i).prop_delay(2:end,:);
                    app.SIM_DATA(i).doppler = app.SIM_DATA(i).doppler(2:end,:);
                    app.SIM_DATA(i).time = app.SIM_DATA(i).time(2:end);
                end
            end

        end
    end
    
    %% Movement task
    methods (Access = private)
        
        function timerMovTime(app)
            app.timer_mov_t = timer;
            
            app.timer_mov_t.TimerFcn = @app.updateMovementPositions;
            app.timer_mov_t.Period = 0.1;
            app.timer_mov_t.StartDelay = 0;
            app.timer_mov_t.ExecutionMode = 'fixedSpacing';
        end

        function timerMoveStart(app)
            start(app.timer_mov_t);
        end

        function timerMoveStop(app)
            stop(app.timer_mov_t);
        end

        function updateMovementPositions(app,~,~)
            
            for i = 1:length(app.NODE_LIST)
                % Compute new X position based on current vx
                tmp_vx = app.NODE_LIST(i).vx;
                tmp_x = app.NODE_LIST(i).x + tmp_vx * app.timer_mov_t.Period;
                % Compute new Y position based on current vy
                tmp_vy =  app.NODE_LIST(i).vy;
                tmp_y = app.NODE_LIST(i).y + tmp_vy * app.timer_mov_t.Period;
                
                % If position X is out of simulation area, then invert vx
                if (tmp_x > app.CallingApp.area_x) || (tmp_x < 0) 
                    tmp_vx = - tmp_vx;
                    tmp_x = app.NODE_LIST(i).x + tmp_vx * app.timer_mov_t.Period;
                end
                
                if (tmp_y > app.CallingApp.area_y) || (tmp_y < 0) % If position Y is out of simulation area, then invert vy
                    tmp_vy = - tmp_vy;
                    tmp_y = app.NODE_LIST(i).y + tmp_vy * app.timer_mov_t.Period;
                end

                % Update new (X,Y) position
                app.NODE_LIST(i).x = tmp_x;
                app.NODE_LIST(i).y = tmp_y;
                
                % Update new (vx, vy) velocities
                app.NODE_LIST(i).vx = tmp_vx;
                app.NODE_LIST(i).vy = tmp_vy;
            end
            
            app.updateNetMap();
            app.updateNodeTable();
        end
        
    end
    
    %% Update position and velocity vectors
    methods (Access = private)
        
        function initNodeDropDown(app)
            str_id = [];

            for i = 1:length(app.NODE_LIST)
                str_id = [str_id string(app.NODE_LIST(i).id)];
            end

            app.NodeDropDown.Items = str_id;
            app.NodeDropDown.Value = app.NodeDropDown.Items(1);
        end

        function enableNodeTable(app, enable)
            if(enable)
                app.ModifyNodeButton.Enable = "on";
                app.VelocityxEditField.Editable = "on";
                app.VelocityyEditField.Editable = "on";
                app.PositionxEditField.Editable = "on";
                app.PositionyEditField.Editable = "on";
            else
                app.ModifyNodeButton.Enable = "off";
                app.VelocityxEditField.Editable = "off";
                app.VelocityyEditField.Editable = "off";
                app.PositionxEditField.Editable = "off";
                app.PositionyEditField.Editable = "off";
            end
        end

        function updateNodeTable(app)
            node_id = str2double(app.NodeDropDown.Value);

            app.PositionxEditField.Value = app.NODE_LIST(node_id).x;
            app.PositionyEditField.Value = app.NODE_LIST(node_id).y;
            app.VelocityxEditField.Value = app.NODE_LIST(node_id).vx;
            app.VelocityyEditField.Value = app.NODE_LIST(node_id).vy;
        end

        function updateNodePosVel(app)
            node_id = str2double(app.NodeDropDown.Value);

            app.NODE_LIST(node_id).x = app.PositionxEditField.Value;
            app.NODE_LIST(node_id).y = app.PositionyEditField.Value;
            app.NODE_LIST(node_id).vx = app.VelocityxEditField.Value;
            app.NODE_LIST(node_id).vy = app.VelocityyEditField.Value;

            app.updateNetMap();
        end
    end

    %% Traffic task
    methods (Access = private)
        
        function timerTrafficInit(app)
            app.timer_traf_t = timer;
            
            app.timer_traf_t.TimerFcn = @app.manageTraffic;
            app.timer_traf_t.Period = 0.1;
            app.timer_traf_t.StartDelay = 0;
            app.timer_traf_t.ExecutionMode = 'fixedSpacing';
        end

        function timerTrafficStart(app)
            start(app.timer_traf_t);
        end

        function timerTrafficStop(app)
            stop(app.timer_traf_t);
        end
        
        function manageTraffic(app,~,~)
            app.generateBroadcastTraffic();
            app.receiveTraffic();
        end

        function generateBroadcastTraffic(app)
            
            n_nodes = length(app.NODE_LIST);

            r = randi([1 n_nodes], 1, 1);
            
            node = app.NODE_LIST(r);
            
            tx_packet = packet();
            rand_delay = rand()/100;
            tx_time = app.simulationTime + rand_delay; 

            if isempty(node.tx_packet)
                pkt_id = 1;
            else
                pkt_id = node.tx_packet(end).pkt_id + 1;
            end

            tx_packet = tx_packet.txPacket(r, tx_time, pkt_id, 255);

            node.tx_packet = [node.tx_packet; tx_packet];

            app.NODE_LIST(r).tx_packet = node.tx_packet;

            app.NETWORK.tx_packet = [app.NETWORK.tx_packet; tx_packet];
            
            tx_packet_array = [tx_packet.tx_time tx_packet.src tx_packet.pkt_id tx_packet.dest];

            app.LOG_PKT.tx_packet = [app.LOG_PKT.tx_packet; tx_packet_array];
        end

        function receiveTraffic(app)
            
            for i = 1:length(app.NETWORK.tx_packet)
                src_add = app.NETWORK.tx_packet(i).src;
                dest_add = app.NETWORK.tx_packet(i).dest;

                if(dest_add == 255)
                    for k = 1:length(app.NODE_LIST)

                        if (k ~= src_add)

                            lb = app.SIM_DATA(k).link_budget(end,src_add);
                            sense = app.NODE_LIST(k).sensitivity;
                            delay = app.SIM_DATA(k).prop_delay(end,src_add);

                            if (lb > sense) && (lb < 0)

                                rx_packet = app.NODE_LIST(k).rx_packet;

                                new_rx_packet = app.NETWORK.tx_packet(i);
                                new_rx_packet.rx_time = new_rx_packet.tx_time + delay;
                                
                                rx_packet = [rx_packet; new_rx_packet];

                                app.NODE_LIST(k).rx_packet = rx_packet;

                                rx_packet_array = [new_rx_packet.rx_time new_rx_packet.src new_rx_packet.pkt_id new_rx_packet.dest k];
                                app.LOG_PKT.rx_packet = [app.LOG_PKT.rx_packet; rx_packet_array];

                            end
                        end

                    end
                else

                end
                
                app.NETWORK.tx_packet = app.NETWORK.tx_packet(2:end);
            end

        end
    end


    %% Close Window Function
    methods (Access = public)
        function closeApp(app)
            delete(app.NetMon)
            delete(app)      
        end
    end
    
    %% Network Mapping Graphic
    methods (Access = private)
        %% Set Connection Circle Visibility
        function setConnectionVisibility(app)
            if strcmp(app.SwitchConnectionCircle.Value, 'On')
                app.showConnection = 1;
                app.addConnectionCircle();
                
            else
                app.showConnection = 0;
                app.deleteConnectionCircle();
            end
        end

        function deleteConnectionCircle(app)
            for i = 1:length(app.NODE_LIST)
                delete(app.NODE_LIST(i).circle_connection);
            end
        end
        
        function addConnectionCircle(app)
            if app.showConnection == 1
                for i = 1:length(app.NODE_LIST)
                    app.NODE_LIST(i).circle_connection = app.drawCircle(app.NODE_LIST(i), app.NODE_LIST(i).range, 1);
                end
            end
        end

        %% Set Node Name Visibility
        function setNodeNameVisibility(app)
            if strcmp(app.SwitchNodeName.Value, 'On')
                app.showName = 1;
                app.addNameText();
            else
                app.showName = 0;
                app.deleteNameText();
            end
        end

        function deleteNameText(app)
            for i = 1:length(app.NODE_LIST)
                delete(app.NODE_LIST(i).text);
            end
        end

        function addNameText(app)
            if app.showName == 1
                for i = 1:length(app.NODE_LIST)
                    app.NODE_LIST(i).text = app.addName(app.NODE_LIST(i));
                end
            end 
        end
        
        %% Update Net MAP
        function updateNetMap(app)

            for i=1:length(app.NODE_LIST)

                delete(app.NODE_LIST(i).circle_node);
                delete(app.NODE_LIST(i).circle_connection);
                delete(app.NODE_LIST(i).text);
                
                app.NODE_LIST(i).circle_node = app.drawCircle(app.NODE_LIST(i), app.radius, 0);

                app.NODE_LIST(i).range = app.connectionRadius(app.NODE_LIST(i));
                
                if app.showConnection == 1
                    app.NODE_LIST(i).circle_connection = app.drawCircle(app.NODE_LIST(i), app.NODE_LIST(i).range, 1);
                end

                if app.showName == 1
                    app.NODE_LIST(i).text = app.addName(app.NODE_LIST(i));
                end             
            end
        end
        
        %% Initialize Net Map
        function initNetMap(app)

            circles = findobj('type', 'rectangle');
            delete(circles);
            
            for i=1:length(app.NODE_LIST)
                app.NODE_LIST(i).circle_node = app.drawCircle(app.NODE_LIST(i), app.radius, 0);

                app.NODE_LIST(i).range = app.connectionRadius(app.NODE_LIST(i));
                
                if app.showConnection == 1
                    app.NODE_LIST(i).circle_connection = app.drawCircle(app.NODE_LIST(i), app.NODE_LIST(i).range, 1);
                end

                if app.showName == 1
                    app.NODE_LIST(i).text = app.addName(app.NODE_LIST(i));
                end
            end
            
        end
        
        function rect = drawCircle(app, node, radius, lineStyle)
            
            pos = [node.x-radius, node.y-radius, radius*2, radius*2];
    
            rect = rectangle( 'Parent', app.UIAxes, ...
                              'Position',pos,...
                              'curvature',[1,1],...
                              'lineWidth',1 );

            if lineStyle == 1
                rect.EdgeColor = [0.8500 0.3250 0.0980];
                rect.LineWidth = 0.5;
            else
                rect.EdgeColor = [0 0.4470 0.7410];
                rect.FaceColor = [0 0.4470 0.7410];
                rect.LineStyle = "-";
            end
        end

        function rect_name = addName(app, node)
            
            get(app.UIAxes, 'position');

            rect_name = text(app.UIAxes, node.x, node.y, string(node.id),...
                              'HorizontalAlignment', 'center', ...
                              'VerticalAlignment','top');

        end

        function range = connectionRadius(app, node)
            range = inverted_friis(node.frequency, -(node.sensitivity-node.tx_power));
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, NODE)
            %%Store the main app object
            app.CallingApp = mainapp;
            app.NODE_LIST = NODE;
            app.NETWORK = network(app.NODE_LIST);
            
            %% Create UI Axes to display Network Nodes
            app.UIFigure.Visible = 'off';
            movegui(app.UIFigure, 'center')

            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.XColor = 'white';
            app.UIAxes.YColor = 'white';
            disableDefaultInteractivity(app.UIAxes);
            
            hold(app.UIAxes, 'on');
            
            %% Get Network Map Dispaly Values
            if strcmp(app.SwitchConnectionCircle.Value, 'On')
                app.showConnection = 1;
            else
                app.showConnection = 0;
            end
            
            if strcmp(app.SwitchNodeName.Value, 'On')
                app.showName = 1;
            else
                app.showName = 0;
            end
            
            %% Initialize SIM_DATA
            sim_data = [];
            for i = 1:length(app.NODE_LIST)
                sim_data = [sim_data SIM_DATA(i)];
            end
            app.SIM_DATA = sim_data;

            %% Display Network NNodes
            app.initNetMap();

            %% Node table initialization
            app.initNodeDropDown();
            app.updateNodeTable();
            
            %% Start Simulation Timer
            app.timerSimInit();
            app.timerSimStart();
            
            %% Start Movement Timer
            app.timerMovTime();

            %% Start Traffic Timer
            app.timerTrafficInit();
            
            
            pause(0.5);

            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: MonitorButton
        function MonitorButtonPushed(app, event)
            app.NetMon = net_monitor(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.NetMon)
            delete(app.CallingApp)
            delete(app)            
        end

        % Value changed function: MovementSwitch
        function MovementSwitchValueChanged(app, event)
            value = app.MovementSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp.Color = 'green';
                app.timerMoveStart();
                app.enableNodeTable(0);
            else
                app.Lamp.Color = 'red';
                app.timerMoveStop();
                app.enableNodeTable(1);
            end
        end

        % Value changed function: SwitchConnectionCircle
        function SwitchConnectionCircleValueChanged(app, event)
            app.setConnectionVisibility();
        end

        % Value changed function: SwitchNodeName
        function SwitchNodeNameValueChanged(app, event)
            app.setNodeNameVisibility();
        end

        % Button pushed function: StopSimButton
        function StopSimButtonPushed(app, event)
            if strcmp(app.StopSimButton.Text, "Stop Sim")
                app.StopSimButton.Text = "Resume Sim";
                app.StopAllTimers();
                assignin('base','SIM_DATA', app.SIM_DATA);
                assignin('base','NODE_LIST', app.NODE_LIST);
                assignin('base','NETWORK', app.NETWORK);
                assignin('base','LOG_PKT', app.LOG_PKT);
            else
                app.StopSimButton.Text = "Stop Sim";
                app.ResumeAllTimers();
            end
        end

        % Value changed function: TrafficSwitch
        function TrafficSwitchValueChanged(app, event)
            value = app.TrafficSwitch.Value;
            
            if strcmp(value, 'On')
                app.Lamp_2.Color = 'green';
                app.timerTrafficStart();

            else
                app.Lamp_2.Color = 'red';
                app.timerTrafficStop();

            end
        end

        % Value changed function: NodeDropDown
        function NodeDropDownValueChanged(app, event)
            app.updateNodeTable();
        end

        % Button pushed function: ModifyNodeButton
        function ModifyNodeButtonPushed(app, event)
            app.updateNodePosVel();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XLim = [0 105];
            app.UIAxes.YLim = [0 105];
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.MonitorButton = uibutton(app.UIFigure, 'push');
            app.MonitorButton.ButtonPushedFcn = createCallbackFcn(app, @MonitorButtonPushed, true);
            app.MonitorButton.Text = 'Monitor';
            app.SimulationTimeLabel = uilabel(app.UIFigure);
            app.SimulationTimeLabel.Text = 'Simulation Time';
            app.SimulationTimeField = uitextarea(app.UIFigure);
            app.NodeConfigurationPanel = uipanel(app.UIFigure);
            app.NodeConfigurationPanel.Title = 'Node Configuration';
            app.NodeDropDownLabel = uilabel(app.NodeConfigurationPanel);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.NodeConfigurationPanel);
            app.NodeDropDown.Editable = 'on';
            app.NodeDropDown.ValueChangedFcn = createCallbackFcn(app, @NodeDropDownValueChanged, true);
            app.PositionxEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.PositionxEditFieldLabel.Text = 'Position: x';
            app.PositionxEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.PositionxEditField.Limits = [0 100];
            app.PositionxEditField.ValueDisplayFormat = '%.2f';
            app.PositionyEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.PositionyEditFieldLabel.Text = 'Position: y';
            app.PositionyEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.PositionyEditField.Limits = [0 100];
            app.PositionyEditField.ValueDisplayFormat = '%.2f';
            app.VelocityxEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.VelocityxEditFieldLabel.Text = 'Velocity: x';
            app.VelocityxEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.VelocityxEditField.Limits = [-10 10];
            app.VelocityxEditField.ValueDisplayFormat = '%.2f';
            app.VelocityyEditFieldLabel = uilabel(app.NodeConfigurationPanel);
            app.VelocityyEditFieldLabel.Text = 'Velocity: y';
            app.VelocityyEditField = uieditfield(app.NodeConfigurationPanel, 'numeric');
            app.VelocityyEditField.Limits = [-10 10];
            app.VelocityyEditField.ValueDisplayFormat = '%.2f';
            app.ModifyNodeButton = uibutton(app.NodeConfigurationPanel, 'push');
            app.ModifyNodeButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyNodeButtonPushed, true);
            app.ModifyNodeButton.Text = 'Modify';
            app.MovementSwitchLabel = uilabel(app.UIFigure);
            app.MovementSwitchLabel.Text = 'Movement';
            app.MovementSwitch = uiswitch(app.UIFigure, 'slider');
            app.MovementSwitch.ValueChangedFcn = createCallbackFcn(app, @MovementSwitchValueChanged, true);
            app.Lamp = uilamp(app.UIFigure);
            app.SwitchConnectionCircle = uiswitch(app.UIFigure, 'slider');
            app.SwitchConnectionCircle.ValueChangedFcn = createCallbackFcn(app, @SwitchConnectionCircleValueChanged, true);
            app.SwitchConnectionCircleLabel = uilabel(app.UIFigure);
            app.SwitchConnectionCircleLabel.Text = 'Show Connection Circle';
            app.SwitchNodeName = uiswitch(app.UIFigure, 'slider');
            app.SwitchNodeName.ValueChangedFcn = createCallbackFcn(app, @SwitchNodeNameValueChanged, true);
            app.SwitchNodeName.Value = 'On';
            app.SwitchNodeNameLabel = uilabel(app.UIFigure);
            app.SwitchNodeNameLabel.Text = 'Show Node Name';
            app.StopSimButton = uibutton(app.UIFigure, 'push');
            app.StopSimButton.ButtonPushedFcn = createCallbackFcn(app, @StopSimButtonPushed, true);
            app.StopSimButton.Text = 'Stop Sim';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.TrafficSwitchLabel = uilabel(app.UIFigure);
            app.TrafficSwitchLabel.Text = 'Traffic';
            app.TrafficSwitch = uiswitch(app.UIFigure, 'slider');
            app.TrafficSwitch.ValueChangedFcn = createCallbackFcn(app, @TrafficSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = network_map(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end