classdef network_pkt_table < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        NetworkStatisticsButton  matlab.ui.control.StateButton
        NetworkTrafficLabel      matlab.ui.control.Label
        ShowButton               matlab.ui.control.StateButton
        PacketsPanel             matlab.ui.container.Panel
        PacketQueueTable         matlab.ui.control.Table
        NodeDropDown             matlab.ui.control.DropDown
        NodeDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp;
        selectedNode;
        nodeApp = [];
        statsApp = [];
        timer_monitor;
    end
    
    %% TIMER
    methods (Access = private)
        function timerInit(app)
            app.timer_monitor = timer;
            
            app.timer_monitor.TimerFcn = @app.updateTable;
            app.timer_monitor.Period = 1;
            app.timer_monitor.StartDelay = 0;
            app.timer_monitor.ExecutionMode = 'fixedSpacing';
            
        end

        function timerStart(app)
            start(app.timer_monitor)
        end
        
        function timerStop(app)
            stop(app.timer_monitor);
        end

        function updateTable(app,~,~)
            app.updatePacketQueueTable();
        end
    end

    %% UTILITIES
    methods (Access = private)

        % Get the number of existing nodes from the main app
        function n_nodes = getNumberOfNodes(app)
            n_nodes = length(app.mainApp.NETWORK.node_list);
        end

        % Get the node id list from the main app
        function node_id = getNodeIdList(app)

            n_nodes = app.getNumberOfNodes();

            node_id = zeros(1, n_nodes);

            for k = 1:n_nodes
                node_id(k) = app.mainApp.NETWORK.node_list(k).id;
            end
        end
    end

    %% DROPDOWN LIST
    methods (Access = private)

        function initNodeDropDown(app)
            
            % Get the number of nodes
            n_nodes = app.getNumberOfNodes();

            % Get node ids list
            node_id_list = app.getNodeIdList();
    
            % Create string structure
            dropdown_options = strings(n_nodes,1);

            for k = 1:n_nodes
                dropdown_options(k) = string(node_id_list(k));
            end
    
            % Assign dropdown options
            app.NodeDropDown.Items = dropdown_options;

            % Initialize dropdown with the first node
            app.NodeDropDown.Value = app.NodeDropDown.Items(1);

        end
    
        function getNodeFromDropDown(app)
            
            app.selectedNode = str2double(app.NodeDropDown.Value);

        end
    end

    %% PACKET QUEUE TABLE
    methods (Access = public)

        function updatePacketQueueTable(app)
            
            packet_queue_list = app.mainApp.NETWORK.sim_data.packet_queue;
            
            app.PacketQueueTable.Data = [];

            if ~isempty(packet_queue_list)

                time = zeros(length(packet_queue_list), 1);
                src = zeros(length(packet_queue_list), 1);
                dest = zeros(length(packet_queue_list), 1);
                pkt_id = zeros(length(packet_queue_list), 1);
                pkt_type = zeros(length(packet_queue_list), 1);

                for k = 1:length(packet_queue_list)
                    time(k) = packet_queue_list(k).timestamp;
                    src(k) = packet_queue_list(k).header.src;
                    dest(k) = packet_queue_list(k).header.dest;

                    pkt_id_tmp = packet_queue_list(k).header.pkt_id;
                    pkt_id(k) = pkt_id_tmp(2);

                    pkt_type(k) = packet_queue_list(k).header.pkt_type;
                end
    
                time = cellstr(num2str(time(:), '%.2f'));
                src = cellstr(num2str(src(:), '%d'));
                dest = cellstr(num2str(dest(:), '%d'));
                pkt_id = cellstr(num2str(pkt_id(:), '%d'));
                pkt_type = cellstr(num2str(pkt_type(:), '%d'));

                app.PacketQueueTable.Data = [time src dest pkt_id pkt_type];
            end

        end

      end

    %% CLOSE APP
    methods (Access = public)

        function closeAppWindow(app)
            app.timerStop();

            for k = 1:length(app.nodeApp)
                app.nodeApp(k).closeAppWindow();
                delete(app.nodeApp(k));
            end

            for k = 1:length(app.statsApp)
                app.statsApp(k).closeAppWindow();
                delete(app.statsApp(k));
            end

            delete(app);

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            app.UIFigure.Visible = 'off';

            % Get information about main app
            app.mainApp = mainApp;

            % Initialize node selection dropdown list
            app.initNodeDropDown();
            
            % Initialize Packet Queue Table
            app.updatePacketQueueTable();
            
            % Initialize Timer
            app.timerInit();
            app.timerStart();

            movegui(app.UIFigure, "northwest");
            
            app.UIFigure.Visible = 'on';

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                app.closeAppWindow();
            catch
                delete(app);
            end
        end

        % Value changed function: ShowButton
        function ShowButtonValueChanged(app, event)
            app.getNodeFromDropDown();

            app.nodeApp = [app.nodeApp pkt_table(app.mainApp, app.selectedNode)];
        end

        % Value changed function: NetworkStatisticsButton
        function NetworkStatisticsButtonValueChanged(app, event)
            app.statsApp = [app.statsApp network_traffic_stats(app.mainApp)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NodeDropDownLabel = uilabel(app.UIFigure);
            app.NodeDropDownLabel.Text = 'Node';
            app.NodeDropDown = uidropdown(app.UIFigure);
            app.NodeDropDown.Items = {'1', '2', '3', '4'};
            app.NodeDropDown.Value = '1';
            app.PacketsPanel = uipanel(app.UIFigure);
            app.PacketsPanel.Title = 'Packets';
            app.PacketQueueTable = uitable(app.PacketsPanel);
            app.PacketQueueTable.ColumnName = {'time'; 'src'; 'dest'; 'pkt_id'; 'pkt_type'};
            app.PacketQueueTable.RowName = {};
            app.ShowButton = uibutton(app.UIFigure, 'state');
            app.ShowButton.ValueChangedFcn = createCallbackFcn(app, @ShowButtonValueChanged, true);
            app.ShowButton.Text = 'Show';
            app.NetworkTrafficLabel = uilabel(app.UIFigure);
            app.NetworkTrafficLabel.Text = 'Network Traffic';
            app.NetworkStatisticsButton = uibutton(app.UIFigure, 'state');
            app.NetworkStatisticsButton.ValueChangedFcn = createCallbackFcn(app, @NetworkStatisticsButtonValueChanged, true);
            app.NetworkStatisticsButton.Text = 'Network Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = network_pkt_table(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end