classdef network_sim_main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NetworkGenerationPanel          matlab.ui.container.Panel
        EIRPdBmEditField                matlab.ui.control.NumericEditField
        EIRPdBmEditFieldLabel           matlab.ui.control.Label
        StartNetworkButton              matlab.ui.control.Button
        RxSensitivitydBmEditField       matlab.ui.control.NumericEditField
        RxSensitivitydBmEditFieldLabel  matlab.ui.control.Label
        NumberofNodesEditField          matlab.ui.control.NumericEditField
        NumberofNodesEditFieldLabel     matlab.ui.control.Label
    end

    %%
    %
    %   TO-DO:
    %%
    properties (Access = public)
        NET_MAP
        NODE
        n_nodes;
        area_x = 100;
        area_y = 100;
        frequency = 2430;
    end
    
    %% Start Network Map Application
    methods (Access = private)
        
        function startNetworkMap(app, node_list)
            app.NET_MAP = network_map(app, node_list);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath("lib/");

            app.UIFigure.Visible = 'off';

            movegui(app.UIFigure, 'center');

            app.UIFigure.Visible = 'on';

        end

        % Button pushed function: StartNetworkButton
        function StartNetworkButtonPushed(app, event)
            %% Generate the Network Nodes
            app.n_nodes = app.NumberofNodesEditField.Value;
            sensitivity = app.RxSensitivitydBmEditField.Value;
            tx_power = app.EIRPdBmEditField.Value;
            
            this.NODE = [];

            for k = 1:app.n_nodes
                
                x = round(app.area_x * rand,2);
                y = round(app.area_y * rand,2);

                vx = round(app.area_x/12 * (2 * rand() - 1),2);
                vy = round(app.area_y/12 * (2 * rand() - 1),2);
                

                this.NODE = [this.NODE node(k, x, y, 'vx', vx, 'vy', vy, ...
                                            'sensitivity', sensitivity, ...
                                            'frequency', app.frequency, ...
                                            'tx_power', tx_power)];
            
            end

            app.NODE = this.NODE;

            app.startNetworkMap(app.NODE);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            try 
                app.NET_MAP.closeApp();

            catch
                
            end
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NetworkGenerationPanel = uipanel(app.UIFigure);
            app.NetworkGenerationPanel.Title = 'Network Generation';
            app.NumberofNodesEditFieldLabel = uilabel(app.NetworkGenerationPanel);
            app.NumberofNodesEditFieldLabel.Text = 'Number of Nodes';
            app.NumberofNodesEditField = uieditfield(app.NetworkGenerationPanel, 'numeric');
            app.NumberofNodesEditField.Limits = [0 Inf];
            app.NumberofNodesEditField.ValueDisplayFormat = '%.0f';
            app.NumberofNodesEditField.Value = 10;
            app.RxSensitivitydBmEditFieldLabel = uilabel(app.NetworkGenerationPanel);
            app.RxSensitivitydBmEditFieldLabel.Text = 'Rx Sensitivity (dBm)';
            app.RxSensitivitydBmEditField = uieditfield(app.NetworkGenerationPanel, 'numeric');
            app.RxSensitivitydBmEditField.Limits = [-Inf 0];
            app.RxSensitivitydBmEditField.RoundFractionalValues = 'on';
            app.RxSensitivitydBmEditField.ValueDisplayFormat = '%.2f';
            app.RxSensitivitydBmEditField.Value = -100;
            app.StartNetworkButton = uibutton(app.NetworkGenerationPanel, 'push');
            app.StartNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @StartNetworkButtonPushed, true);
            app.StartNetworkButton.Text = 'Start';
            app.EIRPdBmEditFieldLabel = uilabel(app.NetworkGenerationPanel);
            app.EIRPdBmEditFieldLabel.Text = 'EIRP (dBm)';
            app.EIRPdBmEditField = uieditfield(app.NetworkGenerationPanel, 'numeric');
            app.EIRPdBmEditField.Limits = [0 100];
            app.EIRPdBmEditField.RoundFractionalValues = 'on';
            app.EIRPdBmEditField.ValueDisplayFormat = '%.2f';
            app.EIRPdBmEditField.Value = 30;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = network_sim_main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end