classdef network_traffic_stats < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        NetworkStatisticsLabel  matlab.ui.control.Label
        UIAxes_Drop             matlab.ui.control.UIAxes
        UIAxes_Broad            matlab.ui.control.UIAxes
        UIAxes_P2p              matlab.ui.control.UIAxes
        UIAxes_Total            matlab.ui.control.UIAxes
    end

    %%
    %
    %   TO DO:
    %       - Monitorizar os plots 
    %           - Atualizar os plots com base em dados da network_map
    %
    %%
    properties (Access = private)
        mainApp % Description
        timer_monitor;
        TOT_PLOT = [];
        P2P_PLOT = [];
        BROAD_PLOT = [];
        DROP_PLOT = [];
    end

    methods (Access = public)
    
        function timerInit(app)
            app.timer_monitor = timer;
            
            app.timer_monitor.TimerFcn = @app.updatePlots;
            app.timer_monitor.Period = 5;
            app.timer_monitor.StartDelay = 0;
            app.timer_monitor.ExecutionMode = 'fixedSpacing';
            
        end

        function timerStart(app)
            start(app.timer_monitor)
        end
        
        function timerStop(app)
            stop(app.timer_monitor);
        end

        function updatePlots(app,~,~)
            app.updatePlotTotalPackets();
            app.updatePlotP2pPackets();
            app.updatePlotBroadPackets();
            app.updatePlotDropPackets();

            drawnow;
        end
    end

    methods (Access = private)
        
        %% TOTAL PACKETS PLOT
        function updatePlotTotalPackets(app)
            
            tx_packets = app.mainApp.NETWORK.sim_data.tx_packets;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_packets;

            legend_plot = ["Tx Packets" "Rx Packets"];
            
            set(app.TOT_PLOT(1), 'XData', tx_packets(:,1), 'YData', tx_packets(:,2));
            set(app.TOT_PLOT(2), 'XData', rx_packets(:,1), 'YData', rx_packets(:,2));
           
            legend(app.TOT_PLOT, legend_plot, 'Location','northwest');
            
        end
        
        function createPlotTotalPackets(app)
            
            tx_packets = app.mainApp.NETWORK.sim_data.tx_packets;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_packets;

            legend_plot = ["Tx Packets" "Rx Packets"];
            

            hold (app.UIAxes_Total, "on");

            app.TOT_PLOT = [app.TOT_PLOT plot(app.UIAxes_Total, tx_packets(:,1), tx_packets(:,2), '.-')];
            app.TOT_PLOT = [app.TOT_PLOT plot(app.UIAxes_Total, rx_packets(:,1), rx_packets(:,2), '.-')];
            
            legend(app.UIAxes_Total, legend_plot, 'Location','northwest');

            hold (app.UIAxes_Total, "off");

        end


        %% P2P PACKETS PLOT
        function updatePlotP2pPackets(app)

            tx_packets = app.mainApp.NETWORK.sim_data.tx_p2p;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_p2p;

            legend_plot = ["Tx Packets" "Rx Packets"];

            set(app.P2P_PLOT(1), 'XData', tx_packets(:,1), 'YData', tx_packets(:,2));
            set(app.P2P_PLOT(2), 'XData', rx_packets(:,1), 'YData', rx_packets(:,2));

            legend(app.P2P_PLOT, legend_plot, 'Location','northwest');

        end

        function createPlotP2pPackets(app)
            
            tx_packets = app.mainApp.NETWORK.sim_data.tx_p2p;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_p2p;

            legend_plot = ["Tx Packets" "Rx Packets"];

            app.P2P_PLOT = [];

            hold (app.UIAxes_P2p, "on");

            app.P2P_PLOT = [app.P2P_PLOT plot(app.UIAxes_P2p, tx_packets(:,1), tx_packets(:,2), '.-')];
            app.P2P_PLOT = [app.P2P_PLOT plot(app.UIAxes_P2p, rx_packets(:,1), rx_packets(:,2), '.-')];

            legend(app.UIAxes_P2p, legend_plot, 'Location','northwest');
            hold (app.UIAxes_P2p, "off");

        end

        %% BROADCAST PACKETS PLOT
        function updatePlotBroadPackets(app)
            
            tx_packets = app.mainApp.NETWORK.sim_data.tx_broad;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_broad;

            legend_plot = ["Tx Packets" "Rx Packets"];

            set(app.BROAD_PLOT(1), 'XData', tx_packets(:,1), 'YData', tx_packets(:,2));
            set(app.BROAD_PLOT(2), 'XData', rx_packets(:,1), 'YData', rx_packets(:,2));

            legend(app.BROAD_PLOT, legend_plot, 'Location','northwest');

        end

        function createPlotBroadPackets(app)
                      
            tx_packets = app.mainApp.NETWORK.sim_data.tx_broad;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_broad;

            legend_plot = ["Tx Packets" "Rx Packets"];

            app.BROAD_PLOT = [];

            hold (app.UIAxes_Broad, "on");

            app.BROAD_PLOT = [app.BROAD_PLOT plot(app.UIAxes_Broad, tx_packets(:,1), tx_packets(:,2), '.-')];
            app.BROAD_PLOT = [app.BROAD_PLOT plot(app.UIAxes_Broad, rx_packets(:,1), rx_packets(:,2), '.-')];

            legend(app.UIAxes_Broad, legend_plot, 'Location','northwest');
            hold (app.UIAxes_Broad, "off");

        end

        %% DROPPED PACKETS PLOT
        function updatePlotDropPackets(app)
            
            tx_packets = app.mainApp.NETWORK.sim_data.tx_packets;
            drop_packets = app.mainApp.NETWORK.sim_data.drop_packets;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_packets;

            legend_plot = ["Tx Packets" "Dropped Packets" "Rx Packets"];

            set(app.DROP_PLOT(1), 'XData', tx_packets(:,1), 'YData', tx_packets(:,2));
            set(app.DROP_PLOT(2), 'XData', drop_packets(:,1), 'YData', drop_packets(:,2));
            set(app.DROP_PLOT(3), 'XData', rx_packets(:,1), 'YData', rx_packets(:,2));

            legend(app.DROP_PLOT, legend_plot, 'Location','northwest');

        end

        function createPlotDropPackets(app)

            tx_packets = app.mainApp.NETWORK.sim_data.tx_packets;
            drop_packets = app.mainApp.NETWORK.sim_data.drop_packets;
            rx_packets = app.mainApp.NETWORK.sim_data.rx_packets;

            legend_plot = ["Tx Packets" "Dropped Packets" "Rx Packets"];
            
            app.DROP_PLOT = [];

            hold (app.UIAxes_Drop, "on");

            app.DROP_PLOT = [app.DROP_PLOT plot(app.UIAxes_Drop, tx_packets(:,1), tx_packets(:,2), '.-')];
            app.DROP_PLOT = [app.DROP_PLOT plot(app.UIAxes_Drop, drop_packets(:,1), drop_packets(:,2), '.-')];
            app.DROP_PLOT = [app.DROP_PLOT plot(app.UIAxes_Drop, rx_packets(:,1), rx_packets(:,2), '.-')];

            legend(app.UIAxes_Drop, legend_plot, 'Location','northwest');
            hold (app.UIAxes_Drop, "off");

        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)

            app.UIFigure.Visible = 'off';

            movegui(app.UIFigure, "southwest");

            app.mainApp = mainApp;
            
            app.createPlotTotalPackets();
            app.createPlotP2pPackets();
            app.createPlotBroadPackets();
            app.createPlotDropPackets();

            app.timerInit();
            app.timerStart();    

            app.UIFigure.Visible = 'on';

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.timerStop();

            delete(app)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_Total = uiaxes(app.UIFigure);
            title(app.UIAxes_Total, 'Total Packets')
            zlabel(app.UIAxes_Total, 'Z')
            app.UIAxes_P2p = uiaxes(app.UIFigure);
            title(app.UIAxes_P2p, 'P2P Packets')
            xlabel(app.UIAxes_P2p, 'time (seconds)')
            zlabel(app.UIAxes_P2p, 'Z')
            app.UIAxes_Broad = uiaxes(app.UIFigure);
            title(app.UIAxes_Broad, 'Broadcast Packets')
            xlabel(app.UIAxes_Broad, 'time (seconds)')
            zlabel(app.UIAxes_Broad, 'Z')
            app.UIAxes_Drop = uiaxes(app.UIFigure);
            title(app.UIAxes_Drop, 'Dropped Packets')
            zlabel(app.UIAxes_Drop, 'Z')
            app.NetworkStatisticsLabel = uilabel(app.UIFigure);
            app.NetworkStatisticsLabel.Text = 'Network Statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = network_traffic_stats(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end